classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        LoadsPanel     matlab.ui.container.Panel
        NewLoadButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        PanelCount = 0;       % Counter to track the number of added panels
        PanelHeight = 80;     % Height of each panel
        PanelSpacing = 10;    % Space between panels
        % newPanel;
        titles = {};
        allpanels;
        deleted_n;
    end
    
    methods (Access = private)
        
        function DropdownValueChanged(app, dropdown, panel)

            if contains(dropdown.Value,'Concentrated')
                panel.Children(1).Visible = 'off';
                panel.Children(2).Visible = 'off';
            elseif dropdown.Value == "Distributed Load"
                panel.Children(1).Visible = 'on';
                panel.Children(2).Visible = 'on';
            
            end

        end

        function deleteMethod2(app,src,event,st)
            now = app.allpanels.app.titles{st};
            % app.titles{st}={};
            delete(now)
            for i = st+1:app.PanelCount
                disp(i);
                disp(app.titles{i})


                current = app.allpanels.app.titles{i};
                current.Position(2) = -80 + (i-1) * (app.PanelHeight + app.PanelSpacing);
                current.Title = app.titles{i-1};
                app.allpanels.app.titles{i-1}=current;
            end
            % delete(app.allpanels.app.titles{end})
            app.titles{end}={};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SpinnerValueChanged(app, event)

        end

        % Button pushed function: NewLoadButton
        function NewLoadButtonPushed(app, event)
            % Increment the panel count
            app.PanelCount = app.PanelCount + 1;
            n = app.PanelCount;
            % Calculate the position for the new panel
            x = 10; % Left margin
            y = -80 + app.PanelCount * (app.PanelHeight + app.PanelSpacing); % Dynamic vertical position
            width = 420; % Width of the panel
            height = app.PanelHeight; % Height of the panel
            app.titles{n} = sprintf('Load %d', app.PanelCount);
            % Create the new panel
            newPanel = uipanel(app.LoadsPanel, ...
                'Title', app.titles{n}, ...
                'Position', [x, y, width, height]);
            app.allpanels.app.titles{n} = newPanel;

            deleteButton = uibutton(newPanel,"Text","Delete",'Position', [360 20 50 30]);%newPanel.Title});
            deleteButton.ButtonPushedFcn = {@app.deleteMethod2,n};
            % Create a dropdown menu inside the panel
            dropdown = uidropdown(newPanel, ...
                'Items', {'Concentrated Force', 'Concentrated Moment','Distributed Load'}, ...
                'Position', [10, 20, 100, 30], ...
                'ValueChangedFcn', @(dd, event) DropdownValueChanged(app, dd, newPanel));

            fieldWidth = 50; % Width of each text field
            fieldSpacing = 10; % Space between text fields
            startX = 120; % Starting X position inside the panel

            for i = 1:4
                uieditfield(newPanel, 'text', ...
                    'Position', [startX + (i-1) * (fieldWidth + fieldSpacing), 20, fieldWidth, 30], ...
                    'Placeholder', sprintf('Field %d', i));
            end

            % Trigger the dropdown callback to initialize the default text fields
            DropdownValueChanged(app, dropdown, newPanel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewLoadButton = uibutton(app.UIFigure, 'push');
            app.NewLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NewLoadButtonPushed, true);
            app.NewLoadButton.Text = 'New Load';
            app.LoadsPanel = uipanel(app.UIFigure);
            app.LoadsPanel.Title = 'Loads';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end